classdef RamanApp2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RamanAppUIFigure            matlab.ui.Figure
        RemoteRamanDetectionSystemLabel  matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        DetectionTab                matlab.ui.container.Tab
        Prob                        matlab.ui.control.Label
        PredictionprobabilityLabel  matlab.ui.control.Label
        SPECTRUM_AC                 matlab.ui.control.Label
        EXDET_IM                    matlab.ui.control.Image
        SaveSpectrumButton          matlab.ui.control.Button
        NALabel                     matlab.ui.control.Label
        CompounddetectedLabel       matlab.ui.control.Label
        GetSpectrumButton           matlab.ui.control.Button
        RamanPlot                   matlab.ui.control.UIAxes
        SettingsTab                 matlab.ui.container.Tab
        RepetitiveGateSetupPanel    matlab.ui.container.Panel
        GateDelay                   matlab.ui.control.EditField
        GateDelayLabel              matlab.ui.control.Label
        GateWidth                   matlab.ui.control.EditField
        GateWidthEditFieldLabel     matlab.ui.control.Label
        SetGateButton               matlab.ui.control.Button
        emailAlertTextArea          matlab.ui.control.TextArea
        emailAlertTextAreaLabel     matlab.ui.control.Label
        ModelDropDown               matlab.ui.control.DropDown
        ModelDropDownLabel          matlab.ui.control.Label
        OffsetEditField             matlab.ui.control.EditField
        OffsetEditFieldLabel        matlab.ui.control.Label
        CoAddedscansEditField       matlab.ui.control.EditField
        CoAddedscansEditFieldLabel  matlab.ui.control.Label
        OnCCDAccumulationEditField  matlab.ui.control.EditField
        OnCCDAccumulationEditFieldLabel  matlab.ui.control.Label
        Image                       matlab.ui.control.Image
    end

    
    properties (Access = private)
        automation_path;
        addin_path;
        support_path; 

        addin_class;
        automation_class;
        support_class;
        out;
        wavelength;
        data;

        RamanModel;

        y;
        Fs;
        T;
        TN;
        NSPEC;
        ref_scale = 0.2;

        %email stuff

        MSG_Subject % Description
            
        MSG_Content % Description
    end    
        
    methods (Access = private)
        
        function email_test(app)
            %%msgbox('email')
            mail = 'ramansensor@iinet.net.au';    % Replace with your email address
            password = 'AH4uK79A1#NI';          % Replace with your email password
            server = 'mail.iinet.net.au';     % Replace with your SMTP server

            % Apply prefs and props
            setpref('Internet','E_mail', mail);
            setpref('Internet','SMTP_Server', server);
            setpref('Internet','SMTP_Username', mail);
            setpref('Internet','SMTP_Password', password);
            setpref('Internet','E_mail',mail);
            setpref('Internet','SMTP_Username',mail);
            setpref('Internet','SMTP_Password',password);

            props = java.lang.System.getProperties;
            props.setProperty( 'mail.smtp.starttls.enable', 'true' );
            props.setProperty('mail.smtp.port', '587'); %587
            props.setProperty('mail.smtp.auth','true');
            % Send the email.  Note that the first input is the address you are sending the email to
            for i = 1:length(app.emailAlertTextArea.Value)
                email_people{1,i} = app.emailAlertTextArea.Value(i);
            end

            sendmail(email_people, app.MSG_Subject, app.MSG_Content);


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
figure(app.RamanAppUIFigure)
app.RamanAppUIFigure.WindowState = 'maximized';
[app.y,app.Fs] = audioread('BOMB.wav');
app.RamanModel = load('Models\LDA_Raw\RamanModel.mat');
app.NSPEC = load('NSPEC.csv');

plot(app.RamanPlot, 1:10, [1:10; 1:10]);
text(app.RamanPlot, 1.1, 9, 'Reference spectrum');
text(app.RamanPlot, 1.1, 1, 'Acquired spectrum');
% app.RamanPlot.Children(3).YData = [1:10];
% app.RamanPlot.Children(1).Position = [2.1 1 0 ]
      app.RamanPlot.Children(1).Position = [1.1*min(app.RamanPlot(1).Children(4).XData) 1.1*min(app.RamanPlot(1).Children(3).YData) 0 ];
      app.RamanPlot.Children(2).Position = [1.1*min(app.RamanPlot(1).Children(4).XData) 0.9*max(app.RamanPlot(1).Children(4).YData) 0 ];
      app.RamanPlot.Children(2).Visible = 'off';
      app.RamanPlot.Children(1).Visible = 'off';
      app.RamanPlot.Children(3).Visible = 'off';
      app.RamanPlot.Children(4).Visible = 'off';
      app.SPECTRUM_AC.Visible = 'off';

app.automation_path = 'C:\Program Files\Princeton Instruments\LightField\PrincetonInstruments.LightField.AutomationV3.dll';
app.addin_path = 'C:\Program Files\Princeton Instruments\LightField\AddInViews\PrincetonInstruments.LightFieldViewV3.dll';
app.support_path = 'C:\Program Files\Princeton Instruments\LightField\PrincetonInstruments.LightFieldAddInSupportServices.dll';
    
app.addin_class = NET.addAssembly(app.addin_path);
app.automation_class = NET.addAssembly(app.automation_path);
app.support_class = NET.addAssembly(app.support_path);
import PrincetonInstruments.LightField.AddIns.*;

%% lightfeild
            app.out.addinbase = PrincetonInstruments.LightField.AddIns.AddInBase();
            app.out.automation = PrincetonInstruments.LightField.Automation.Automation(true,[]);
            app.out.application = app.out.automation.LightFieldApplication;
            app.out.experiment = app.out.application.Experiment;

%%app.lfi = lfm(); %% need .li.li???
%%app.lfi.load_experiment('Raman10m');
app.out.experiment.Load('Raman10m');


app.RamanModel = load('RamanModel.mat')
app.T = readtable('test1.csv');

app.TN = cell(701,1);

for i = 1:701
    app.TN{i,:} = strcat('W', num2str(i));
end
app.TN{702,:} = 'type';
app.T.Properties.VariableNames = app.TN;


%yfit = app.RamanModel.trainedModel.predictFcn(app.T)
%msgbox(yfit{1})


        end

        % Button pushed function: GetSpectrumButton
        function GetSpectrumButtonPushed(app, event)
            app.RamanAppUIFigure.Color = [0.94,0.94,0.94];
            app.EXDET_IM.Visible = 'off';
            app.NALabel.Text = "N/A";
            app.RamanPlot.Children(1).Visible = 'off';
            app.RamanPlot.Children(2).Visible = 'off';
            app.RamanPlot.Children(3).Visible = 'off';
            app.RamanPlot.Children(4).Visible = 'off';
            app.SPECTRUM_AC.Visible = 'on';
            drawnow();
            
import System.IO.FileAccess;
            app.out.experiment.Acquire();
            accessed_wavelength = 0;
            
            
while app.out.experiment.IsRunning % During acquisition...
                % Case where wavelength is empty
                if accessed_wavelength == 0 && isempty(app.out.experiment.SystemColumnCalibration)
                    fprintf('Wavelength information not available\n');
                    app.wavelength = [];
                    accessed_wavelength = 1;
                elseif accessed_wavelength == 0
                    wavelen_len = app.out.experiment.SystemColumnCalibration.Length;
                    assert(wavelen_len >= 1);
                    app.wavelength = zeros(wavelen_len, 1);
                    for i = 0:wavelen_len-1 % load wavelength info directly from LightField instance
                        app.wavelength(i+1) = app.out.experiment.SystemColumnCalibration.Get(i);
                    end
                    accessed_wavelength = 1;
                end
end

lastfile = app.out.application.FileManager.GetRecentlyAcquiredFileNames.GetItem(0);
imageset = app.out.application.FileManager.OpenFile(lastfile,FileAccess.Read);
            
          
            if imageset.Regions.Length == 1
                if imageset.Frames == 1
                    frame = imageset.GetFrame(0,0);
                    app.data = reshape(frame.GetData().double,frame.Width,frame.Height)';
                    
                    
                else
                    app.data = [];
                    for i = 0:imageset.Frames-1
                        frame = imageset.GetFrame(0,i);
                        app.data = cat(3,app.data,reshape(frame.GetData().double,frame.Width,frame.Height,1)');
                    end
                   
                    
                end
            else
               
                app.data = cell(imageset.Regions.Length,1);
                for j = 0:imageset.Regions.Length-1
                    if imageset.Frames == 1
                        frame = imageset.GetFrame(j,0);
                        buffer = reshape(frame.GetData().double,frame.Width,frame.Height)';
                    else
                        buffer = [];
                        for i = 0:imageset.Frames-1
                            frame = imageset.GetFrame(j,i);
                            buffer = cat(3,buffer,reshape(frame.GetData().double,frame.Width,frame.Height,1)');
                        end
                    end
                    app.data{j+1} = buffer;
                end
            end
            
            
            app.RamanPlot(1).Children(3).XData = ((1/(354.76e-9)))/100 - (1./(app.wavelength(158:858)*1e-9))/100;
            app.RamanPlot(1).Children(3).YData = mean(app.data(56:239, 158:858));


            %%%%% update here
        switch app.ModelDropDown.Value
            case '1'
                app.RamanModel = load('Models\LDA_Raw\RamanModel.mat')
                app.T = array2table(app.RamanPlot.Children(3).YData);
                app.TN = cell(701,1);
                for i = 1:701
                    app.TN{i,:} = strcat('W', num2str(i));
                end
                app.T.Properties.VariableNames = app.TN;
                %yfit = app.RamanModel.trainedModel.predictFcn(app.T);
                [yfit,score,cost] = predict(app.RamanModel.trainedModel.ClassificationDiscriminant,app.T);
              
            
            case '2'
                app.RamanModel = load('Models\LDA_1gDerSTD\RamanModel.mat');
                sx = load('Models\LDA_1gDerSTD\sx.csv');
                mx = load('Models\LDA_1gDerSTD\mx.csv');
                
                mod_data = app.RamanPlot.Children(3).YData;
                [ni, mi] = size(mod_data);
                g =10;
                s = g-1;
                AI = zeros(1, 692);
                for i = g:mi
                   AI(1, i-s) = mod_data(1,i) - mod_data(1,(i-s)) ;
                end           
                
                AI = (AI - mx)./sx;
                
                app.T = array2table(AI);
                app.TN = cell(692,1);
                for i = 1:692
                    app.TN{i,:} = strcat('W', num2str(i));
                end
                app.T.Properties.VariableNames = app.TN;
                %yfit = app.RamanModel.trainedModel.predictFcn(app.T);
                [yfit,score,cost] = predict(app.RamanModel.trainedModel.ClassificationDiscriminant,app.T);
            
            case '3'
                app.RamanModel = load('Models\LDA_2GderSTD\RamanModel.mat');
                sx = load('Models\LDA_2GderSTD\sx.csv');
                mx = load('Models\LDA_2GderSTD\mx.csv');
                
                mod_data = app.RamanPlot.Children(3).YData;
                [ni, mi] = size(mod_data);
                g =10;
                s = g-1;
                AI = zeros(1, 692);
                for i = g:mi
                   AI(1, i-s) = mod_data(1,i) - mod_data(1,(i-s)) ;
                end         
                [ni, mi] = size(AI);
                AI2 = zeros(1, 683);
                for i = g:mi
                   AI2(1, i-s) = AI(1,i) - AI(1,(i-s)) ;
                end  
                
                 AI2 = (AI2 - mx)./sx;
                
                app.T = array2table(AI2);
                app.TN = cell(683,1);
                for i = 1:683
                    app.TN{i,:} = strcat('W', num2str(i));
                end
                app.T.Properties.VariableNames = app.TN;
                %yfit = app.RamanModel.trainedModel.predictFcn(app.T);
                [yfit,score,cost] = predict(app.RamanModel.trainedModel.ClassificationDiscriminant,app.T);
                
            case '4'
                app.RamanModel = load('Models\LDA_1GDer10\RamanModel.mat');
                
                mod_data = app.RamanPlot.Children(3).YData;
                [ni, mi] = size(mod_data);
                g =10;
                s = g-1;
                AI = zeros(1, 692);
                for i = g:mi
                   AI(1, i-s) = mod_data(1,i) - mod_data(1,(i-s)) ;
                end           
                                             
                app.T = array2table(AI);
                app.TN = cell(692,1);
                for i = 1:692
                    app.TN{i,:} = strcat('W', num2str(i));
                end
                app.T.Properties.VariableNames = app.TN;
                [yfit,score,cost] = predict(app.RamanModel.trainedModel.ClassificationDiscriminant,app.T);
            otherwise
                msgbox('fail')
        end
            %%%%
            
%             app.T = array2table(app.RamanPlot.Children(1).YData);
%             app.TN = cell(701,1);
% 
% for i = 1:701
%     app.TN{i,:} = strcat('W', num2str(i));
% end
%             app.T.Properties.VariableNames = app.TN;
%           yfit = app.RamanModel.trainedModel.predictFcn(app.T);
          
        app.SPECTRUM_AC.Visible = 'off';
        app.MSG_Subject = 'Potential Hazardous material detected Alert: Message From the QUT Raman Sensor';
        
        switch yfit{1}
            case {'AN','HP'}
                app.EXDET_IM.ImageSource = 'explovive.png';
                app.EXDET_IM.Visible = 'on';
                app.RamanAppUIFigure.Visible = 'off';
                app.RamanAppUIFigure.Visible = 'on';
                app.RamanAppUIFigure.Color = [1,0,0];
                if yfit{1} == "AN"
                    app.NALabel.Text = "Ammonium Nitrate";
            app.RamanPlot(1).Children(4).XData = ((1/(354.76e-9)))/100 - (1./(app.wavelength(158:858)*1e-9))/100;
            app.RamanPlot(1).Children(4).YData = app.ref_scale*(max(app.RamanPlot(1).Children(3).YData)- min(app.RamanPlot(1).Children(3).YData))*app.NSPEC(1,:) + max(app.RamanPlot(1).Children(3).YData);
            app.Prob.Text = strcat(num2str(100*score(1)), {' %'});
            app.MSG_Content = {'Dear Friends,', '', strcat(['This is the QUT Raman Sensor. On the ', date(), ' at ', datestr(rem(now,1)), ' Ammonium Nitrate, an explosive, was detected with a probability of ', num2str(score(1)), ' %. Please investigate further']), '','Regards,', 'The QUT Raman Sensor', '', 'You have 10 seconds before I self destruct!'};
            %email_test(app);        
            %NSPEC(1,:)
                end
                if yfit{1} == "HP"
                    app.NALabel.Text = "Hydrogen Peroxide";
                    app.RamanPlot(1).Children(4).XData = ((1/(354.76e-9)))/100 - (1./(app.wavelength(158:858)*1e-9))/100;
                    app.RamanPlot(1).Children(4).YData = app.ref_scale*(max(app.RamanPlot(1).Children(3).YData)- min(app.RamanPlot(1).Children(3).YData))*app.NSPEC(7,:) + max(app.RamanPlot(1).Children(3).YData);
                    %NSPEC(7,:)
                    app.Prob.Text = strcat(num2str(100*score(5)), {' %'});
                    app.MSG_Content = {'Dear Friends,', '', strcat(['This is the QUT Raman Sensor. On the ', date(), ' at ', datestr(rem(now,1)), ' hydrogen peroxide, an explosive precursor, was detected with a probability of ', num2str(score(1)), ' %. Please investigate further']), '','Regards,', 'The QUT Raman Sensor', '', 'You have 10 seconds before I self destruct!'};
                    %email_test(app)
                end
                sound(app.y,app.Fs);
                
            case {'BaSu'}
                app.NALabel.Text = "Barium Sulfate";
                app.RamanPlot(1).Children(4).XData = ((1/(354.76e-9)))/100 - (1./(app.wavelength(158:858)*1e-9))/100;
                app.RamanPlot(1).Children(4).YData = app.ref_scale*(max(app.RamanPlot(1).Children(3).YData)- min(app.RamanPlot(1).Children(3).YData))*app.NSPEC(4,:) + max(app.RamanPlot(1).Children(3).YData);
                %NSPEC(4,:)
                app.Prob.Text = strcat(num2str(100*score(2)), {' %'});
                app.RamanAppUIFigure.Color = [0 1 0];
            case {'Erythritol'}
                app.NALabel.Text = "Erythritol";
                app.RamanPlot(1).Children(4).XData = ((1/(354.76e-9)))/100 - (1./(app.wavelength(158:858)*1e-9))/100;
                app.RamanPlot(1).Children(4).YData = app.ref_scale*(max(app.RamanPlot(1).Children(3).YData)- min(app.RamanPlot(1).Children(3).YData))*app.NSPEC(5,:) + max(app.RamanPlot(1).Children(3).YData);
                %NSPEC(5,:)
                app.Prob.Text = strcat(num2str(100*score(3)), {' %'});
                app.RamanAppUIFigure.Color = [0 1 0];
            case {'Ethanol'}
                app.NALabel.Text = "Ethanol";
                app.RamanPlot(1).Children(4).XData = ((1/(354.76e-9)))/100 - (1./(app.wavelength(158:858)*1e-9))/100;
                app.RamanPlot(1).Children(4).YData = app.ref_scale*(max(app.RamanPlot(1).Children(3).YData)- min(app.RamanPlot(1).Children(3).YData))*app.NSPEC(6,:) + max(app.RamanPlot(1).Children(3).YData);
                %NSPEC(6,:)
                app.EXDET_IM.ImageSource = 'combustible.png';
                app.EXDET_IM.Visible = 'on';
                app.RamanAppUIFigure.Visible = 'off';
                app.RamanAppUIFigure.Visible = 'on';
                app.RamanAppUIFigure.Color = [1.00,0.41,0.16];
                app.Prob.Text = strcat(num2str(100*score(4)), {' %'});
                sound(app.y,app.Fs);
                app.MSG_Content = {'Dear Friends,', '', strcat(['This is the QUT Raman Sensor. On the ', date(), ' at ', datestr(rem(now,1)), ' ethanol, a flammable material, was detected with a probability of ', num2str(score(1)), ' %. Please investigate further']), '','Regards,', 'The QUT Raman Sensor', '', 'You have 10 seconds before I self destruct!'};
                %email_test(app)
            case {'marb'}
                app.NALabel.Text = "Calcium Carbonate";
                app.RamanPlot(1).Children(4).XData = ((1/(354.76e-9)))/100 - (1./(app.wavelength(158:858)*1e-9))/100;
                app.RamanPlot(1).Children(4).YData = app.ref_scale*(max(app.RamanPlot(1).Children(3).YData)- min(app.RamanPlot(1).Children(3).YData))*app.NSPEC(8,:) + max(app.RamanPlot(1).Children(3).YData);
                %NSPEC(8,:)
                app.Prob.Text = strcat(num2str(100*score(10)), {' %'});
                app.RamanAppUIFigure.Color = [0 1 0];
            case {'Melamine'}
                app.NALabel.Text = "Melamine";
                app.RamanPlot(1).Children(4).XData = ((1/(354.76e-9)))/100 - (1./(app.wavelength(158:858)*1e-9))/100;
                app.RamanPlot(1).Children(4).YData = app.ref_scale*(max(app.RamanPlot(1).Children(3).YData)- min(app.RamanPlot(1).Children(3).YData))*app.NSPEC(9,:) + max(app.RamanPlot(1).Children(3).YData);
                %NSPEC(9,:)
                app.Prob.Text = strcat(num2str(100*score(6)), {' %'});
                app.RamanAppUIFigure.Color = [0 1 0];
            case {'Paracetamol'}
                app.NALabel.Text = "Paracetamol";
                app.RamanPlot(1).Children(4).XData = ((1/(354.76e-9)))/100 - (1./(app.wavelength(158:858)*1e-9))/100;
                app.RamanPlot(1).Children(4).YData = app.ref_scale*(max(app.RamanPlot(1).Children(3).YData)- min(app.RamanPlot(1).Children(3).YData))*app.NSPEC(10,:) + max(app.RamanPlot(1).Children(3).YData);
                %NSPEC(10,:)

                app.Prob.Text = strcat(num2str(100*score(7)), {' %'});
                app.RamanAppUIFigure.Color = [0 1 0];
            case {'Tefflon'}
                app.NALabel.Text = "Teflon";
                app.RamanPlot(1).Children(4).XData = ((1/(354.76e-9)))/100 - (1./(app.wavelength(158:858)*1e-9))/100;
                app.RamanPlot(1).Children(4).YData = app.ref_scale*(max(app.RamanPlot(1).Children(3).YData)- min(app.RamanPlot(1).Children(3).YData))*app.NSPEC(12,:) + max(app.RamanPlot(1).Children(3).YData);
                %NSPEC(12,:)
                app.Prob.Text = strcat(num2str(100*score(9)), {' %'});
                app.RamanAppUIFigure.Color = [0 1 0];
              case {'Quartz'}
                  app.NALabel.Text = "Quartz";
                  app.RamanPlot(1).Children(4).XData = ((1/(354.76e-9)))/100 - (1./(app.wavelength(158:858)*1e-9))/100;
                  app.RamanPlot(1).Children(4).YData = app.ref_scale*(max(app.RamanPlot(1).Children(3).YData)- min(app.RamanPlot(1).Children(3).YData))*app.NSPEC(11,:) + max(app.RamanPlot(1).Children(3).YData);
                  %NSPEC(11,:)
                  app.Prob.Text = strcat(num2str(100*score(8)), {' %'});
                  app.RamanAppUIFigure.Color = [0 1 0];
            otherwise
                app.NALabel.Text = "unknown";
         end
      app.RamanPlot.Children(1).Position = [1.1*min(app.RamanPlot(1).Children(3).XData) 1.1*min(app.RamanPlot(1).Children(3).YData) 0 ];
      app.RamanPlot.Children(2).Position = [1.1*min(app.RamanPlot(1).Children(4).XData) 0.9*max(app.RamanPlot(1).Children(4).YData) 0 ];
      app.RamanPlot.Children(1).Visible = 'on';
      app.RamanPlot.Children(2).Visible = 'on';
      app.RamanPlot.Children(3).Visible = 'on';
      app.RamanPlot.Children(4).Visible = 'on';
      drawnow();
      
      switch yfit{1}
          case {'AN', 'HP', 'Ethanol'}
              email_test(app);   
          otherwise
      end
      
            
        end

        % Button pushed function: SaveSpectrumButton
        function SaveSpectrumButtonPushed(app, event)
            [File,Path] = uiputfile('*.txt','Select the path and file to log data');
            F = strcat(Path, File);
            %FIDSAVE = fopen(F, 'w');
%             fprintf(FIDSAVE,'%s \r\n', app.RamanPlot.Children(1).Xdata); 
%             fprintf(FIDSAVE,'%s \r\n', app.RamanPlot.Children(1).Ydata); %% app.data?
            writematrix(app.data, F);            

           % fclose(FIDSAVE);
                     
            
        end

        % Close request function: RamanAppUIFigure
        function RamanAppUIFigureCloseRequest(app, event)
            app.out.automation.Dispose();
            delete(app)
            
        end

        % Value changed function: GateWidth
        function GateWidthValueChanged(app, event)
            value = app.GateWidth.Value;
           
        end

        % Value changed function: OnCCDAccumulationEditField
        function OnCCDAccumulationEditFieldValueChanged(app, event)
            %value = str2int(app.OnCCDAccumulationEditField.Value);
            value = (app.OnCCDAccumulationEditField.Value);
            
            if ((rem(str2double(value),1)==0) && str2double(value)>0 )
                app.out.experiment.SetValue(PrincetonInstruments.LightField.AddIns.CameraSettings.ReadoutControlAccumulations, value);
            else
                msgbox('Accumulations needs to be a positive integer')
                app.OnCCDAccumulationEditField.Value = '200';
            end
            
        end

        % Button pushed function: SetGateButton
        function SetGateButtonPushed(app, event)
            width = app.GateWidth.Value;
            delay = app.GateDelay.Value;
            
            app.out.SetValue(PrincetonInstruments.LightField.AddIns.CameraSettings.GatingRepetitiveGate, PrincetonInstruments.LightField.AddIns.Pulse(width,delay));

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RamanAppUIFigure = uifigure('Visible', 'off');
            app.RamanAppUIFigure.Name = 'RamanApp';
            app.RamanAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @RamanAppUIFigureCloseRequest, true);
            app.Image = uiimage(app.RamanAppUIFigure);
            app.Image.ImageSource = 'QUT.png';
            app.TabGroup = uitabgroup(app.RamanAppUIFigure);
            app.DetectionTab = uitab(app.TabGroup);
            app.DetectionTab.Title = 'Detection';
            app.RamanPlot = uiaxes(app.DetectionTab);
            title(app.RamanPlot, 'Raman Spectrum')
            xlabel(app.RamanPlot, 'Wavenumber /cm^- ^1')
            ylabel(app.RamanPlot, {'Raman intensity'; ''})
            app.GetSpectrumButton = uibutton(app.DetectionTab, 'push');
            app.GetSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @GetSpectrumButtonPushed, true);
            app.GetSpectrumButton.Text = 'Get Spectrum';
            app.CompounddetectedLabel = uilabel(app.DetectionTab);
            app.CompounddetectedLabel.Text = 'Compound detected:';
            app.NALabel = uilabel(app.DetectionTab);
            app.NALabel.Text = 'N/A';
            app.SaveSpectrumButton = uibutton(app.DetectionTab, 'push');
            app.SaveSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSpectrumButtonPushed, true);
            app.SaveSpectrumButton.Text = 'Save Spectrum';
            app.EXDET_IM = uiimage(app.DetectionTab);
            app.EXDET_IM.Visible = 'off';
            app.EXDET_IM.ImageSource = 'explovive.png';
            app.SPECTRUM_AC = uilabel(app.DetectionTab);
            app.SPECTRUM_AC.Text = 'Spectrum collection in progress';
            app.PredictionprobabilityLabel = uilabel(app.DetectionTab);
            app.PredictionprobabilityLabel.Text = 'Prediction probability:';
            app.Prob = uilabel(app.DetectionTab);
            app.Prob.Text = '0 %';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.OnCCDAccumulationEditFieldLabel = uilabel(app.SettingsTab);
            app.OnCCDAccumulationEditFieldLabel.Text = 'On-CCD Accumulation';
            app.OnCCDAccumulationEditField = uieditfield(app.SettingsTab, 'text');
            app.OnCCDAccumulationEditField.ValueChangedFcn = createCallbackFcn(app, @OnCCDAccumulationEditFieldValueChanged, true);
            app.OnCCDAccumulationEditField.Value = '200';
            app.CoAddedscansEditFieldLabel = uilabel(app.SettingsTab);
            app.CoAddedscansEditFieldLabel.Text = 'Co-Added scans';
            app.CoAddedscansEditField = uieditfield(app.SettingsTab, 'text');
            app.CoAddedscansEditField.Value = '1';
            app.OffsetEditFieldLabel = uilabel(app.SettingsTab);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetEditField = uieditfield(app.SettingsTab, 'text');
            app.OffsetEditField.Value = '0';
            app.ModelDropDownLabel = uilabel(app.SettingsTab);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.SettingsTab);
            app.ModelDropDown.Items = {'Raw', '1st Der. STD', '2nd Der.STD', '1st Der.'};
            app.ModelDropDown.ItemsData = {'1', '2', '3', '4'};
            app.ModelDropDown.Value = '1';
            app.emailAlertTextAreaLabel = uilabel(app.SettingsTab);
            app.emailAlertTextAreaLabel.Text = 'email Alert';
            app.emailAlertTextArea = uitextarea(app.SettingsTab);
            app.emailAlertTextArea.Value = {'joshua.carroll@qut.edu.au'; 'w.martens@qut.edu.au'; 'ramansennsor@iinet.net.au'};
            app.RepetitiveGateSetupPanel = uipanel(app.SettingsTab);
            app.RepetitiveGateSetupPanel.Title = 'Repetitive Gate Setup';
            app.SetGateButton = uibutton(app.RepetitiveGateSetupPanel, 'push');
            app.SetGateButton.ButtonPushedFcn = createCallbackFcn(app, @SetGateButtonPushed, true);
            app.SetGateButton.Text = 'Set Gate';
            app.GateWidthEditFieldLabel = uilabel(app.RepetitiveGateSetupPanel);
            app.GateWidthEditFieldLabel.Text = 'Gate Width';
            app.GateWidth = uieditfield(app.RepetitiveGateSetupPanel, 'text');
            app.GateWidth.ValueChangedFcn = createCallbackFcn(app, @GateWidthValueChanged, true);
            app.GateWidth.Value = '20';
            app.GateDelayLabel = uilabel(app.RepetitiveGateSetupPanel);
            app.GateDelayLabel.Text = 'Gate Delay';
            app.GateDelay = uieditfield(app.RepetitiveGateSetupPanel, 'text');
            app.GateDelay.Value = '100';
            app.RemoteRamanDetectionSystemLabel = uilabel(app.RamanAppUIFigure);
            app.RemoteRamanDetectionSystemLabel.Text = 'Remote Raman Detection System';
            % Show the figure after all components are created
            app.RamanAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RamanApp2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RamanAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RamanAppUIFigure)
        end
    end
end